classdef AudioAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PlotKindButtonGroup      matlab.ui.container.ButtonGroup
        TimeAnalysisButton       matlab.ui.control.RadioButton
        SpectrogramButton        matlab.ui.control.RadioButton
        FrequencyAnalysisButton  matlab.ui.control.RadioButton
        HistogramButton          matlab.ui.control.RadioButton
        AutocorrelationButton    matlab.ui.control.RadioButton
        PlotButton               matlab.ui.control.Button
        RecordButton             matlab.ui.control.StateButton
        BrowseButton             matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        PlayButton               matlab.ui.control.StateButton
        playbuttonButton         matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        OtherMetadataPanel       matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        x = 0;
        fs = 0;
        N = 0;
        X = 0;
        t = 0;
        % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('.wav');
            if isequal(file, 0)
            else
                app.EditField.Value = fullfile(path, file)
            end
            [app.x, app.fs] = audioread(app.EditField.Value);  % 匯入音訊檔
            app.X = app.x(:, 1);                        % 找到第一聲道
            app.N = length(app.X);                      % 總長度
            app.t = (0:app.N-1)/app.fs;                     % 單位時間
            
        end

        % Selection changed function: PlotKindButtonGroup
        function PlotKindButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotKindButtonGroup.SelectedObject;
            
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            if value == 1
                fs = 44100;
                nBits = 16;
                nChannels = 1;
                recObj = audiorecorder(fs, nBits, nChannels)
                record(recObj)
            else
                stop(recObj)
                play(recObj)
                filename = 'recordedAudio.wav'
                audiowrite(filename, y, fs)
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        if app.TimeAnalysisButton.Value == true
                plot(app.UIAxes, app.t,app.X);
                grid(app.UIAxes,'minor')
                app.UIAxes.XLim = ([0 max(app.t)])
                app.UIAxes.YLim = ([-1.1*max(abs(app.X)) 1.1*max(abs(app.X))])
                app.UIAxes.XLabel.String = ('Time(s)')
                app.UIAxes.YLabel.String = ('Amplitude')
                app.UIAxes.Title.String = ('Time Analysis')
        elseif app.SpectrogramButton.Value == true
                spectrogram(app.X, [], [], [], app.fs, 'yaxis')
                box on
                if app.t < 1
                xlabel('Time(ms)')
                ylabel('Frequency(kHz)')
                title('Spectrogram')
                h = colorbar;
                ylabel(h, 'Magnitude(dB)')
                else
                xlabel('Time(s)')
                ylabel('Frequency(kHz)')
                title('Spectrogram')
                h = colorbar;
                ylabel(h, 'Magnitude(dB)')
                end
        elseif app.FrequencyAnalysisButton.Value == true
            w = hann(app.N, 'periodic');
            [Xx, f] = periodogram(app.X, w, app.N, app.fs, 'power');
            Xx = 20*log10(Xx);
            plot(app.UIAxes, f, Xx);
            app.UIAxes.XLim = ([0 max(f)])
            app.UIAxes.YLim = ([min(Xx) max(Xx)])
                grid(app.UIAxes,'minor')
                app.UIAxes.XLabel.String = ('Frequency(Hz)')
                app.UIAxes.YLabel.String = ('Magnitude')
                app.UIAxes.Title.String = ('Frequency Analysis')
        elseif app.HistogramButton.Value == true
            histogram(app.UIAxes, app.X)
            grid(app.UIAxes,'on')
            app.UIAxes.XLim = ([-1.1*max(abs(app.X)) 1.1*max(abs(app.X))]) 
            app.UIAxes.YLim = ([0 1.1*max(abs(2*app.fs))]) 
            app.UIAxes.XLabel.String = ('Amplitude')
                app.UIAxes.YLabel.String = ('Samples')
                app.UIAxes.Title.String = ('Histogram')
        elseif app.AutocorrelationButton.Value == true
            [R, lags] = xcorr(app.X, 'coeff');
            tc = lags/app.fs;
            plot(app.UIAxes, tc, R)
            app.UIAxes.XLim = ([-1*max(abs(tc)) 1*max(abs(tc))])
            app.UIAxes.YLim = ([0 1.1*max(abs(R))])
            grid(app.UIAxes, 'on');
            app.UIAxes.XLabel.String = ('Delay, s')
            app.UIAxes.YLabel.String = ('自相關係數')
            app.UIAxes.Title.String = ('訊號自相關圖')
        end
      
        end

        % Callback function
        function PlayButtonPushed(app, event)
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            playsound = audioplayer(app.x, app.fs)
           play(playsound)
        end

        % Button pushed function: playbuttonButton
        function playbuttonButtonPushed(app, event)
            [x, fs] = audioread(app.EditField.Value);
            playsound = audioplayer(x, fs);
           play(playsound)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {504, 504};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotKindButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotKindButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotKindButtonGroupSelectionChanged, true);
            app.PlotKindButtonGroup.Title = 'Plot Kind';
            app.TimeAnalysisButton = uiradiobutton(app.PlotKindButtonGroup);
            app.TimeAnalysisButton.Text = 'Time Analysis';
            app.TimeAnalysisButton.Value = true;
            app.SpectrogramButton = uiradiobutton(app.PlotKindButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.FrequencyAnalysisButton = uiradiobutton(app.PlotKindButtonGroup);
            app.FrequencyAnalysisButton.Text = 'Frequency Analysis';
            app.HistogramButton = uiradiobutton(app.PlotKindButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.AutocorrelationButton = uiradiobutton(app.PlotKindButtonGroup);
            app.AutocorrelationButton.Text = 'Autocorrelation';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RecordButton = uibutton(app.LeftPanel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PlayButton = uibutton(app.LeftPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.playbuttonButton = uibutton(app.LeftPanel, 'push');
            app.playbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @playbuttonButtonPushed, true);
            app.playbuttonButton.Text = 'playbutton';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OtherMetadataPanel = uipanel(app.RightPanel);
            app.OtherMetadataPanel.Title = 'Other Metadata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end