classdef speechRecognitionMARKX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        RecordButton        matlab.ui.control.StateButton
        EditField           matlab.ui.control.EditField
        BrowseButton        matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        TimeDomainTab       matlab.ui.container.Tab
        UIAxes              matlab.ui.control.UIAxes
        AutocorrelationTab  matlab.ui.container.Tab
        UIAxes2             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fs = 0;
        recObj = 0;
        x = 0;
        X = 0;
        Y = 0;
        N=0;
        n = 0;
        t=0;
        Xeff = 0;
        Xcorr = 0;
        lagsX = 0;
        F = 0;
        i = 3;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            if app.RecordButton.Value == 1
               app.fs = 44100;
               nBits = 24;
               nChannels = 1;
               app.recObj = audiorecorder(app.fs, nBits, nChannels);
               record(app.recObj);
               pause(1.5);
               stop(app.recObj);
               app.x = getaudiodata(app.recObj);
               app.N = length(app.x);
               app.t = (0:app.N-1)/app.fs;
               while app.RecordButton.Value == 1
               record(app.recObj);
               pause(1.5);
               stop(app.recObj);
               app.x = vertcat(app.x, getaudiodata(app.recObj));
               app.N = length(app.x);
               app.t = (0:app.N-1)/app.fs;
               if app.RecordButton.Value == 0
                   break;
               end
               plot(app.UIAxes, app.t,app.x);
                grid(app.UIAxes,'minor')
                app.UIAxes.XLim = ([0 max(app.t)]);
                %app.UIAxes.YLim = ([-1.1*max(abs(app.x)) 1.1*max(abs(app.x))]);
                app.UIAxes.XLabel.String = ('Time(s)');
                app.UIAxes.YLabel.String = ('Amplitude');
                app.UIAxes.Title.String = ('Time Analysis');
                
                app.X = getaudiodata(app.recObj);
                app.n = floor(length(app.X)/app.i);
                app.Xeff = zeros(app.n, app.i);
                for p = 1:app.i
                    if p==1
                    app.Xeff(:,p) = app.X(1:p*app.n);
                    else
                    app.Xeff(:,p) = app.X((p-1)*app.n+1:p*app.n);
                    end
                end
                app.Xcorr = zeros(2*app.n-1, app.i);
                app.lagsX = zeros(2*app.n-1, app.i);
                for p=1:app.i
                [app.Xcorr(:,p), app.lagsX(:,p)] = xcorr(app.Xeff(:,p), 'coeff');
                end
                [XC, lagsXC] = xcorr(app.X, 'coeff');
                [peaksXcorr, indicesXcorr] = findpeaks(XC);
                [~, indexXcorr] = sort(peaksXcorr, 'descend');
                RTS = abs(indicesXcorr(indexXcorr(3))-length(app.X))/app.fs;
                FS = 1/RTS;
                for p=1:app.i
                [peaksXcorr, indicesXcorr] = findpeaks(app.Xcorr(:,p));
                [~, indexXcorr] = sort(peaksXcorr, 'descend');
                RT = abs(indicesXcorr(indexXcorr(3))-app.n)/app.fs;
                if FS<800
                    if 1/RT <=FS+100 && 1/RT>=FS-100
                       app.F = vertcat(app.F, 1/RT);
                    end
                end
                end
                plot(app.UIAxes2, app.F);
                grid(app.UIAxes2,'minor')
                app.UIAxes.YLabel.String = ('Hz');
                app.UIAxes.Title.String = ('Autocorrelation Analysis');


               end
            end
            if app.RecordButton.Value == 0
               stop(app.recObj); 
               app.x = vertcat(app.x, getaudiodata(app.recObj));
               app.N = length(app.x);
               for p = 1:app.N
                   if abs(app.x(p)) >0.3
                       app.x(p) = 0;
                   end
               end
               app.t = (0:app.N-1)/app.fs;
               plot(app.UIAxes, app.t,app.x);
                grid(app.UIAxes,'minor')
                app.UIAxes.XLim = ([0 max(app.t)]);
                %app.UIAxes.YLim = ([-1.1*max(abs(app.x)) 1.1*max(abs(app.x))]);
                app.UIAxes.XLabel.String = ('Time(s)');
                app.UIAxes.YLabel.String = ('Amplitude');
                app.UIAxes.Title.String = ('Time Analysis');
               audiowrite("mySpeech.wav", app.x, app.fs);
               app.EditField.Value = fullfile("mySpeech.wav");


               app.X = getaudiodata(app.recObj);
                app.n = floor(length(app.X)/app.i);
                app.Xeff = zeros(app.n, app.i);
                for p = 1:app.i
                    if p==1
                    app.Xeff(:,p) = app.X(1:p*app.n);
                    else
                    app.Xeff(:,p) = app.X((p-1)*app.n+1:p*app.n);
                    end
                end
                app.Xcorr = zeros(2*app.n-1, app.i);
                app.lagsX = zeros(2*app.n-1, app.i);
                for p=1:app.i
                [app.Xcorr(:,p), app.lagsX(:,p)] = xcorr(app.Xeff(:,p), 'coeff');
                end
                [XC, lagsXC] = xcorr(app.X, 'coeff');
                [peaksXcorr, indicesXcorr] = findpeaks(XC);
                [~, indexXcorr] = sort(peaksXcorr, 'descend');
                RTS = abs(indicesXcorr(indexXcorr(3))-length(app.X))/app.fs;
                FS = 1/RTS;
                for p=1:app.i
                [peaksXcorr, indicesXcorr] = findpeaks(app.Xcorr(:,p));
                [~, indexXcorr] = sort(peaksXcorr, 'descend');
                RT = abs(indicesXcorr(indexXcorr(3))-app.n)/app.fs;
                if FS<800
                    if 1/RT <=FS+100 && 1/RT>=FS-100
                       app.F = vertcat(app.F, 1/RT);
                    end
                end
                end
                plot(app.UIAxes2, app.F);
                grid(app.UIAxes2,'minor')
                app.UIAxes.YLabel.String = ('Hz');
                app.UIAxes.Title.String = ('Autocorrelation Analysis');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            soundsc(app.x, app.fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RecordButton = uibutton(app.LeftPanel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.Text = 'Browse';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'TimeDomain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AutocorrelationTab = uitab(app.TabGroup);
            app.AutocorrelationTab.Title = 'Autocorrelation';
            app.UIAxes2 = uiaxes(app.AutocorrelationTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speechRecognitionMARKX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end