classdef speechRecognitionMARKVIII < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        EditField                    matlab.ui.control.EditField
        RecordButton                 matlab.ui.control.StateButton
        BrowseButton                 matlab.ui.control.Button
        TrainingFunctionButtonGroup  matlab.ui.container.ButtonGroup
        ForwardButton                matlab.ui.control.RadioButton
        BackButton                   matlab.ui.control.RadioButton
        LeftButton                   matlab.ui.control.RadioButton
        RightButton                  matlab.ui.control.RadioButton
        StopButton                   matlab.ui.control.RadioButton
        StartTrainButton             matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        aLabel                       matlab.ui.control.Label
        aEditField                   matlab.ui.control.EditField
        bLabel                       matlab.ui.control.Label
        bEditField                   matlab.ui.control.EditField
        cLabel                       matlab.ui.control.Label
        cEditField                   matlab.ui.control.EditField
        dLabel                       matlab.ui.control.Label
        dEditField                   matlab.ui.control.EditField
        eLabel                       matlab.ui.control.Label
        eEditField                   matlab.ui.control.EditField
        forwardLabel                 matlab.ui.control.Label
        forwardEditField             matlab.ui.control.EditField
        backEditFieldLabel           matlab.ui.control.Label
        backEditField                matlab.ui.control.EditField
        leftEditFieldLabel           matlab.ui.control.Label
        leftEditField                matlab.ui.control.EditField
        rightEditFieldLabel          matlab.ui.control.Label
        rightEditField               matlab.ui.control.EditField
        stopEditFieldLabel           matlab.ui.control.Label
        stopEditField                matlab.ui.control.EditField
        GridLayout2                  matlab.ui.container.GridLayout
        StopButton_2                 matlab.ui.control.Button
        TrainingEditFieldLabel       matlab.ui.control.Label
        TrainingEditField            matlab.ui.control.EditField
    end


    properties (Access = public)
        recObj = 0;
        fs = 0;
        Xeff = 0;
        x=0;
        X=0;
        Xeff1corr = 0;
        Xeff2corr = 0;
        Xeff3corr = 0;
        Xeff4corr = 0;
        Xeff5corr = 0;
        Xeff6corr = 0;
        Xeff7corr = 0;
        Y1eff1corr = 0;
        Y1eff2corr = 0;
        Y1eff3corr = 0;
        Y1eff4corr = 0;
        Y1eff5corr = 0;
        Y1eff6corr = 0;
        Y1eff7corr = 0;
        Y2eff1corr = 0;
        Y2eff2corr = 0;
        Y2eff3corr = 0;
        Y2eff4corr = 0;
        Y2eff5corr = 0;
        Y2eff6corr = 0;
        Y2eff7corr = 0;
        Y3eff1corr = 0;
        Y3eff2corr = 0;
        Y3eff3corr = 0;
        Y3eff4corr = 0;
        Y3eff5corr = 0;
        Y3eff6corr = 0;
        Y3eff7corr = 0;
        Y4eff1corr = 0;
        Y4eff2corr = 0;
        Y4eff3corr = 0;
        Y4eff4corr = 0;
        Y4eff5corr = 0;
        Y4eff6corr = 0;
        Y4eff7corr = 0;
        Y5eff1corr = 0;
        Y5eff2corr = 0;
        Y5eff3corr = 0;
        Y5eff4corr = 0;
        Y5eff5corr = 0;
        Y5eff6corr = 0;
        Y5eff7corr = 0;
        a = 0;
        b = 0;
        c = 0;
        d = 0;
        e = 0;
        forward = 0;
        back = 0;
        left = 0;
        right = 0;
        stop = 0;
        
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%forward
[y1, fsy1]=audioread('forward.wav');
Y1 = y1(:, 1);                      
[peaksY1, indicesY1] = findpeaks(Y1);
startpeakY1 = find(peaksY1>=0.01, 1, 'first');
startpointY1 = indicesY1(startpeakY1-10);
endpeakY1 = find(peaksY1>=0.01, 1, 'last');
endpointY1 = indicesY1(endpeakY1+10);
Y1eff = Y1(startpointY1:endpointY1);

Y1eff1 = Y1eff(1:floor(length(Y1eff)/7));
Y1eff2 = Y1eff(ceil(length(Y1eff)/7):floor(length(Y1eff)*2/7));
Y1eff3 = Y1eff(ceil(length(Y1eff)*2/7):floor(length(Y1eff)*3/7));
Y1eff4 = Y1eff(ceil(length(Y1eff)*3/7):floor(length(Y1eff)*4/7));
Y1eff5 = Y1eff(ceil(length(Y1eff)*4/7):floor(length(Y1eff)*5/7));
Y1eff6 = Y1eff(ceil(length(Y1eff)*5/7):floor(length(Y1eff)*6/7));
Y1eff7 = Y1eff(ceil(length(Y1eff)*6/7):floor(length(Y1eff)));

[app.Y1eff1corr, lagsY1eff1] = xcorr(Y1eff1, 'coeff');
tcY1eff1 = lagsY1eff1/fsy1;
[app.Y1eff2corr, lagsY1eff2] = xcorr(Y1eff2, 'coeff');
tcY1eff2 = lagsY1eff2/fsy1;
[app.Y1eff3corr, lagsY1eff3] = xcorr(Y1eff3, 'coeff');
tcY1eff3 = lagsY1eff3/fsy1;
[app.Y1eff4corr, lagsY1eff4] = xcorr(Y1eff4, 'coeff');
tcY1eff4 = lagsY1eff4/fsy1;
[app.Y1eff5corr, lagsY1eff5] = xcorr(Y1eff5, 'coeff');
tcY1eff5 = lagsY1eff5/fsy1;
[app.Y1eff6corr, lagsY1eff6] = xcorr(Y1eff6, 'coeff');
tcY1eff6 = lagsY1eff6/fsy1;
[app.Y1eff7corr, lagsY1eff7] = xcorr(Y1eff7, 'coeff');
tcY1eff7 = lagsY1eff7/fsy1;


%back
[y2, fsy2]=audioread('back.wav');
Y2 = y2(:, 1);                        % 嚙踝蕭嚙衝一嚙緯嚙瘩
[peaksY2, indicesY2] = findpeaks(Y2);
startpeakY2 = find(peaksY2>=0.01, 1, 'first');
startpointY2 = indicesY2(startpeakY2-10);
endpeakY2 = find(peaksY2>=0.01, 1, 'last');
endpointY2 = indicesY2(endpeakY2+10);
Y2eff = Y2(startpointY2:endpointY2);

Y2eff1 = Y2eff(1:floor(length(Y2eff)/7));
Y2eff2 = Y2eff(ceil(length(Y2eff)/7):floor(length(Y2eff)*2/7));
Y2eff3 = Y2eff(ceil(length(Y2eff)*2/7):floor(length(Y2eff)*3/7));
Y2eff4 = Y2eff(ceil(length(Y2eff)*3/7):floor(length(Y2eff)*4/7));
Y2eff5 = Y2eff(ceil(length(Y2eff)*4/7):floor(length(Y2eff)*5/7));
Y2eff6 = Y2eff(ceil(length(Y2eff)*5/7):floor(length(Y2eff)*6/7));
Y2eff7 = Y2eff(ceil(length(Y2eff)*6/7):floor(length(Y2eff)));

[app.Y2eff1corr, lagsY2eff1] = xcorr(Y2eff1, 'coeff');
tcY2eff1 = lagsY2eff1/fsy2;
[app.Y2eff2corr, lagsY2eff2] = xcorr(Y2eff2, 'coeff');
tcY2eff2 = lagsY2eff2/fsy2;
[app.Y2eff3corr, lagsY2eff3] = xcorr(Y2eff3, 'coeff');
tcY2eff3 = lagsY2eff3/fsy2;
[app.Y2eff4corr, lagsY2eff4] = xcorr(Y2eff4, 'coeff');
tcY2eff4 = lagsY2eff4/fsy2;
[app.Y2eff5corr, lagsY2eff5] = xcorr(Y2eff5, 'coeff');
tcY2eff5 = lagsY2eff5/fsy2;
[app.Y2eff6corr, lagsY2eff6] = xcorr(Y2eff6, 'coeff');
tcY2eff6 = lagsY2eff6/fsy2;
[app.Y2eff7corr, lagsY2eff7] = xcorr(Y2eff7, 'coeff');
tcY2eff7 = lagsY2eff7/fsy2;


%left
[y3, fsy3]=audioread('left.wav');
Y3 = y3(:, 1);                        % 嚙踝蕭嚙衝一嚙緯嚙瘩
[peaksY3, indicesY3] = findpeaks(Y3);
startpeakY3 = find(peaksY3>=0.01, 1, 'first');
startpointY3 = indicesY3(startpeakY3-10);
endpeakY3 = find(peaksY3>=0.01, 1, 'last');
endpointY3 = indicesY3(endpeakY3+10);
Y3eff = Y3(startpointY3:endpointY3);

Y3eff1 = Y3eff(1:floor(length(Y3eff)/7));
Y3eff2 = Y3eff(ceil(length(Y3eff)/7):floor(length(Y3eff)*2/7));
Y3eff3 = Y3eff(ceil(length(Y3eff)*2/7):floor(length(Y3eff)*3/7));
Y3eff4 = Y3eff(ceil(length(Y3eff)*3/7):floor(length(Y3eff)*4/7));
Y3eff5 = Y3eff(ceil(length(Y3eff)*4/7):floor(length(Y3eff)*5/7));
Y3eff6 = Y3eff(ceil(length(Y3eff)*5/7):floor(length(Y3eff)*6/7));
Y3eff7 = Y3eff(ceil(length(Y3eff)*6/7):floor(length(Y3eff)));

[app.Y3eff1corr, lagsY3eff1] = xcorr(Y3eff1, 'coeff');
tcY3eff1 = lagsY3eff1/fsy3;
[app.Y3eff2corr, lagsY3eff2] = xcorr(Y3eff2, 'coeff');
tcY3eff2 = lagsY3eff2/fsy3;
[app.Y3eff3corr, lagsY3eff3] = xcorr(Y3eff3, 'coeff');
tcY3eff3 = lagsY3eff3/fsy3;
[app.Y3eff4corr, lagsY3eff4] = xcorr(Y3eff4, 'coeff');
tcY3eff4 = lagsY3eff4/fsy3;
[app.Y3eff5corr, lagsY3eff5] = xcorr(Y3eff5, 'coeff');
tcY3eff5 = lagsY3eff5/fsy3;
[app.Y3eff6corr, lagsY3eff6] = xcorr(Y3eff6, 'coeff');
tcY3eff6 = lagsY3eff6/fsy3;
[app.Y3eff7corr, lagsY3eff7] = xcorr(Y3eff7, 'coeff');
tcY3eff7 = lagsY3eff7/fsy3;

%right
[y4, fsy4]=audioread('right.wav');
Y4 = y4(:, 1);                        % 嚙踝蕭嚙衝一嚙緯嚙瘩
[peaksY4, indicesY4] = findpeaks(Y4);
startpeakY4 = find(peaksY4>=0.01, 1, 'first');
startpointY4 = indicesY4(startpeakY4-10);
endpeakY4 = find(peaksY4>=0.01, 1, 'last');
endpointY4 = indicesY4(endpeakY4+10);
Y4eff = Y4(startpointY4:endpointY4);

Y4eff1 = Y4eff(1:floor(length(Y4eff)/7));
Y4eff2 = Y4eff(ceil(length(Y4eff)/7):floor(length(Y4eff)*2/7));
Y4eff3 = Y4eff(ceil(length(Y4eff)*2/7):floor(length(Y4eff)*3/7));
Y4eff4 = Y4eff(ceil(length(Y4eff)*3/7):floor(length(Y4eff)*4/7));
Y4eff5 = Y4eff(ceil(length(Y4eff)*4/7):floor(length(Y4eff)*5/7));
Y4eff6 = Y4eff(ceil(length(Y4eff)*5/7):floor(length(Y4eff)*6/7));
Y4eff7 = Y4eff(ceil(length(Y4eff)*6/7):floor(length(Y4eff)));

[app.Y4eff1corr, lagsY4eff1] = xcorr(Y4eff1, 'coeff');
tcY4eff1 = lagsY4eff1/fsy4;
[app.Y4eff2corr, lagsY4eff2] = xcorr(Y4eff2, 'coeff');
tcY4eff2 = lagsY4eff2/fsy4;
[app.Y4eff3corr, lagsY4eff3] = xcorr(Y4eff3, 'coeff');
tcY4eff3 = lagsY4eff3/fsy4;
[app.Y4eff4corr, lagsY4eff4] = xcorr(Y4eff4, 'coeff');
tcY4eff4 = lagsY4eff4/fsy4;
[app.Y4eff5corr, lagsY4eff5] = xcorr(Y4eff5, 'coeff');
tcY4eff5 = lagsY4eff5/fsy4;
[app.Y4eff6corr, lagsY4eff6] = xcorr(Y4eff6, 'coeff');
tcY4eff6 = lagsY4eff6/fsy4;
[app.Y4eff7corr, lagsY4eff7] = xcorr(Y4eff7, 'coeff');
tcY4eff7 = lagsY4eff7/fsy4;


%right
[y5, fsy5]=audioread('stop.wav');
Y5 = y5(:, 1);                        % 嚙踝蕭嚙衝一嚙緯嚙瘩
[peaksY5, indicesY5] = findpeaks(Y5);
startpeakY5 = find(peaksY5>=0.01, 1, 'first');
startpointY5 = indicesY5(startpeakY5-10);
endpeakY5 = find(peaksY5>=0.01, 1, 'last');
endpointY5 = indicesY5(endpeakY5+10);
Y5eff = Y5(startpointY5:endpointY5);

Y5eff1 = Y5eff(1:floor(length(Y5eff)/7));
Y5eff2 = Y5eff(ceil(length(Y5eff)/7):floor(length(Y5eff)*2/7));
Y5eff3 = Y5eff(ceil(length(Y5eff)*2/7):floor(length(Y5eff)*3/7));
Y5eff4 = Y5eff(ceil(length(Y5eff)*3/7):floor(length(Y5eff)*4/7));
Y5eff5 = Y5eff(ceil(length(Y5eff)*4/7):floor(length(Y5eff)*5/7));
Y5eff6 = Y5eff(ceil(length(Y5eff)*5/7):floor(length(Y5eff)*6/7));
Y5eff7 = Y5eff(ceil(length(Y5eff)*6/7):floor(length(Y5eff)));

[app.Y5eff1corr, lagsY5eff1] = xcorr(Y5eff1, 'coeff');
tcY5eff1 = lagsY5eff1/fsy5;
[app.Y5eff2corr, lagsY5eff2] = xcorr(Y5eff2, 'coeff');
tcY5eff2 = lagsY5eff2/fsy5;
[app.Y5eff3corr, lagsY5eff3] = xcorr(Y5eff3, 'coeff');
tcY5eff3 = lagsY5eff3/fsy5;
[app.Y5eff4corr, lagsY5eff4] = xcorr(Y5eff4, 'coeff');
tcY5eff4 = lagsY5eff4/fsy5;
[app.Y5eff5corr, lagsY5eff5] = xcorr(Y5eff5, 'coeff');
tcY5eff5 = lagsY5eff5/fsy5;
[app.Y5eff6corr, lagsY5eff6] = xcorr(Y5eff6, 'coeff');
tcY5eff6 = lagsY5eff6/fsy5;
[app.Y5eff7corr, lagsY5eff7] = xcorr(Y5eff7, 'coeff');
tcY5eff7 = lagsY5eff7/fsy5;             
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            if value == 1
               app.fs = 44100;
               nBits = 24;
               nChannels = 2;
               app.recObj = audiorecorder(app.fs, nBits, nChannels);
               record(app.recObj)
            end
            if value == 0
               stop(app.recObj)
               app.x = getaudiodata(app.recObj);
               app.X = app.x(:, 1);                        
               [peaksX, indicesX] = findpeaks(app.X);
               startpeakX = find(peaksX>=0.01, 1, 'first');
               startpointX = indicesX(startpeakX);
               endpeakX = find(peaksX>=0.01, 1, 'last');
               endpointX = indicesX(endpeakX);
               app.Xeff = app.X(startpointX:endpointX);     
               audiowrite('mySpeech.wav', app.Xeff, app.fs)
               app.EditField.Value = fullfile("mySpeech.wav");
               
               
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            soundsc(app.Xeff, app.fs);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('.wav');
            if isequal(file, 0)
            else
            app.EditField.Value = fullfile(path, file);
            [app.x, app.fs] = audioread(app.EditField.Value);  % 匯入音訊檔
            app.X = app.x(:,1);
            [peaksX, indicesX] = findpeaks(app.X);
            startpeakX = find(peaksX>=0.01, 1, 'first');
            startpointX = indicesX(startpeakX);
            endpeakX = find(peaksX>=0.01, 1, 'last');
            endpointX = indicesX(endpeakX);
            app.Xeff = app.X(startpointX:endpointX);
            end
        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
        Xeff1 = app.Xeff(1:floor(length(app.Xeff)/7));
        Xeff2 = app.Xeff(ceil(length(app.Xeff)/7):floor(length(app.Xeff)*2/7));
        Xeff3 = app.Xeff(ceil(length(app.Xeff)*2/7):floor(length(app.Xeff)*3/7));
        Xeff4 = app.Xeff(ceil(length(app.Xeff)*3/7):floor(length(app.Xeff)*4/7));
        Xeff5 = app.Xeff(ceil(length(app.Xeff)*4/7):floor(length(app.Xeff)*5/7));
        Xeff6 = app.Xeff(ceil(length(app.Xeff)*5/7):floor(length(app.Xeff)*6/7));
        Xeff7 = app.Xeff(ceil(length(app.Xeff)*6/7):floor(length(app.Xeff)));

        [app.Xeff1corr, lagsXeff1] = xcorr(Xeff1, 'coeff');
        tcXeff1 = lagsXeff1/app.fs;
        [app.Xeff2corr, lagsXeff2] = xcorr(Xeff2, 'coeff');
        tcXeff2 = lagsXeff2/app.fs;
        [app.Xeff3corr, lagsXeff3] = xcorr(Xeff3, 'coeff');
        tcXeff3 = lagsXeff3/app.fs;
        [app.Xeff4corr, lagsXeff4] = xcorr(Xeff4, 'coeff');
        tcXeff4 = lagsXeff4/app.fs;
        [app.Xeff5corr, lagsXeff5] = xcorr(Xeff5, 'coeff');
        tcXeff5 = lagsXeff5/app.fs;
        [app.Xeff6corr, lagsXeff6] = xcorr(Xeff6, 'coeff');
        tcXeff6 = lagsXeff6/app.fs;
        [app.Xeff7corr, lagsXeff7] = xcorr(Xeff7, 'coeff');
        tcXeff7 = lagsXeff7/app.fs;
        
forward1 = app.Xeff1corr((length(app.Xeff1corr)+1)/2-1750 : (length(app.Xeff1corr)+1)/2+1750) - app.Y1eff1corr((length(app.Y1eff1corr)+1)/2-1750 : (length(app.Y1eff1corr)+1)/2+1750);
forward2 = app.Xeff2corr((length(app.Xeff2corr)+1)/2-1750 : (length(app.Xeff2corr)+1)/2+1750) - app.Y1eff2corr((length(app.Y1eff2corr)+1)/2-1750 : (length(app.Y1eff2corr)+1)/2+1750);
forward3 = app.Xeff3corr((length(app.Xeff3corr)+1)/2-1750 : (length(app.Xeff3corr)+1)/2+1750) - app.Y1eff3corr((length(app.Y1eff3corr)+1)/2-1750 : (length(app.Y1eff3corr)+1)/2+1750);
forward4 = app.Xeff4corr((length(app.Xeff4corr)+1)/2-1750 : (length(app.Xeff4corr)+1)/2+1750) - app.Y1eff4corr((length(app.Y1eff4corr)+1)/2-1750 : (length(app.Y1eff4corr)+1)/2+1750);
forward5 = app.Xeff5corr((length(app.Xeff5corr)+1)/2-1750 : (length(app.Xeff5corr)+1)/2+1750) - app.Y1eff5corr((length(app.Y1eff5corr)+1)/2-1750 : (length(app.Y1eff5corr)+1)/2+1750);
forward6 = app.Xeff6corr((length(app.Xeff6corr)+1)/2-1750 : (length(app.Xeff6corr)+1)/2+1750) - app.Y1eff6corr((length(app.Y1eff6corr)+1)/2-1750 : (length(app.Y1eff6corr)+1)/2+1750);
forward7 = app.Xeff7corr((length(app.Xeff7corr)+1)/2-1750 : (length(app.Xeff7corr)+1)/2+1750) - app.Y1eff7corr((length(app.Y1eff7corr)+1)/2-1750 : (length(app.Y1eff7corr)+1)/2+1750);

back1 = app.Xeff1corr((length(app.Xeff1corr)+1)/2-1750 : (length(app.Xeff1corr)+1)/2+1750) - app.Y2eff1corr((length(app.Y2eff1corr)+1)/2-1750 : (length(app.Y2eff1corr)+1)/2+1750);
back2 = app.Xeff2corr((length(app.Xeff2corr)+1)/2-1750 : (length(app.Xeff2corr)+1)/2+1750) - app.Y2eff2corr((length(app.Y2eff2corr)+1)/2-1750 : (length(app.Y2eff2corr)+1)/2+1750);
back3 = app.Xeff3corr((length(app.Xeff3corr)+1)/2-1750 : (length(app.Xeff3corr)+1)/2+1750) - app.Y2eff3corr((length(app.Y2eff3corr)+1)/2-1750 : (length(app.Y2eff3corr)+1)/2+1750);
back4 = app.Xeff4corr((length(app.Xeff4corr)+1)/2-1750 : (length(app.Xeff4corr)+1)/2+1750) - app.Y2eff4corr((length(app.Y2eff4corr)+1)/2-1750 : (length(app.Y2eff4corr)+1)/2+1750);
back5 = app.Xeff5corr((length(app.Xeff5corr)+1)/2-1750 : (length(app.Xeff5corr)+1)/2+1750) - app.Y2eff5corr((length(app.Y2eff5corr)+1)/2-1750 : (length(app.Y2eff5corr)+1)/2+1750);
back6 = app.Xeff6corr((length(app.Xeff6corr)+1)/2-1750 : (length(app.Xeff6corr)+1)/2+1750) - app.Y2eff6corr((length(app.Y2eff6corr)+1)/2-1750 : (length(app.Y2eff6corr)+1)/2+1750);
back7 = app.Xeff7corr((length(app.Xeff7corr)+1)/2-1750 : (length(app.Xeff7corr)+1)/2+1750) - app.Y2eff7corr((length(app.Y2eff7corr)+1)/2-1750 : (length(app.Y2eff7corr)+1)/2+1750);

left1 = app.Xeff1corr((length(app.Xeff1corr)+1)/2-1750 : (length(app.Xeff1corr)+1)/2+1750) - app.Y3eff1corr((length(app.Y3eff1corr)+1)/2-1750 : (length(app.Y3eff1corr)+1)/2+1750);
left2 = app.Xeff2corr((length(app.Xeff2corr)+1)/2-1750 : (length(app.Xeff2corr)+1)/2+1750) - app.Y3eff2corr((length(app.Y3eff2corr)+1)/2-1750 : (length(app.Y3eff2corr)+1)/2+1750);
left3 = app.Xeff3corr((length(app.Xeff3corr)+1)/2-1750 : (length(app.Xeff3corr)+1)/2+1750) - app.Y3eff3corr((length(app.Y3eff3corr)+1)/2-1750 : (length(app.Y3eff3corr)+1)/2+1750);
left4 = app.Xeff4corr((length(app.Xeff4corr)+1)/2-1750 : (length(app.Xeff4corr)+1)/2+1750) - app.Y3eff4corr((length(app.Y3eff4corr)+1)/2-1750 : (length(app.Y3eff4corr)+1)/2+1750);
left5 = app.Xeff5corr((length(app.Xeff5corr)+1)/2-1750 : (length(app.Xeff5corr)+1)/2+1750) - app.Y3eff5corr((length(app.Y3eff5corr)+1)/2-1750 : (length(app.Y3eff5corr)+1)/2+1750);
left6 = app.Xeff6corr((length(app.Xeff6corr)+1)/2-1750 : (length(app.Xeff6corr)+1)/2+1750) - app.Y3eff6corr((length(app.Y3eff6corr)+1)/2-1750 : (length(app.Y3eff6corr)+1)/2+1750);
left7 = app.Xeff7corr((length(app.Xeff7corr)+1)/2-1750 : (length(app.Xeff7corr)+1)/2+1750) - app.Y3eff7corr((length(app.Y3eff7corr)+1)/2-1750 : (length(app.Y3eff7corr)+1)/2+1750);

right1 = app.Xeff1corr((length(app.Xeff1corr)+1)/2-1750 : (length(app.Xeff1corr)+1)/2+1750) - app.Y4eff1corr((length(app.Y4eff1corr)+1)/2-1750 : (length(app.Y4eff1corr)+1)/2+1750);
right2 = app.Xeff2corr((length(app.Xeff2corr)+1)/2-1750 : (length(app.Xeff2corr)+1)/2+1750) - app.Y4eff2corr((length(app.Y4eff2corr)+1)/2-1750 : (length(app.Y4eff2corr)+1)/2+1750);
right3 = app.Xeff3corr((length(app.Xeff3corr)+1)/2-1750 : (length(app.Xeff3corr)+1)/2+1750) - app.Y4eff3corr((length(app.Y4eff3corr)+1)/2-1750 : (length(app.Y4eff3corr)+1)/2+1750);
right4 = app.Xeff4corr((length(app.Xeff4corr)+1)/2-1750 : (length(app.Xeff4corr)+1)/2+1750) - app.Y4eff4corr((length(app.Y4eff4corr)+1)/2-1750 : (length(app.Y4eff4corr)+1)/2+1750);
right5 = app.Xeff5corr((length(app.Xeff5corr)+1)/2-1750 : (length(app.Xeff5corr)+1)/2+1750) - app.Y4eff5corr((length(app.Y4eff5corr)+1)/2-1750 : (length(app.Y4eff5corr)+1)/2+1750);
right6 = app.Xeff6corr((length(app.Xeff6corr)+1)/2-1750 : (length(app.Xeff6corr)+1)/2+1750) - app.Y4eff6corr((length(app.Y4eff6corr)+1)/2-1750 : (length(app.Y4eff6corr)+1)/2+1750);
right7 = app.Xeff7corr((length(app.Xeff7corr)+1)/2-1750 : (length(app.Xeff7corr)+1)/2+1750) - app.Y4eff7corr((length(app.Y4eff7corr)+1)/2-1750 : (length(app.Y4eff7corr)+1)/2+1750);

stop1 = app.Xeff1corr((length(app.Xeff1corr)+1)/2-1750 : (length(app.Xeff1corr)+1)/2+1750) - app.Y5eff1corr((length(app.Y5eff1corr)+1)/2-1750 : (length(app.Y5eff1corr)+1)/2+1750);
stop2 = app.Xeff2corr((length(app.Xeff2corr)+1)/2-1750 : (length(app.Xeff2corr)+1)/2+1750) - app.Y5eff2corr((length(app.Y5eff2corr)+1)/2-1750 : (length(app.Y5eff2corr)+1)/2+1750);
stop3 = app.Xeff3corr((length(app.Xeff3corr)+1)/2-1750 : (length(app.Xeff3corr)+1)/2+1750) - app.Y5eff3corr((length(app.Y5eff3corr)+1)/2-1750 : (length(app.Y5eff3corr)+1)/2+1750);
stop4 = app.Xeff4corr((length(app.Xeff4corr)+1)/2-1750 : (length(app.Xeff4corr)+1)/2+1750) - app.Y5eff4corr((length(app.Y5eff4corr)+1)/2-1750 : (length(app.Y5eff4corr)+1)/2+1750);
stop5 = app.Xeff5corr((length(app.Xeff5corr)+1)/2-1750 : (length(app.Xeff5corr)+1)/2+1750) - app.Y5eff5corr((length(app.Y5eff5corr)+1)/2-1750 : (length(app.Y5eff5corr)+1)/2+1750);
stop6 = app.Xeff6corr((length(app.Xeff6corr)+1)/2-1750 : (length(app.Xeff6corr)+1)/2+1750) - app.Y5eff6corr((length(app.Y5eff6corr)+1)/2-1750 : (length(app.Y5eff6corr)+1)/2+1750);
stop7 = app.Xeff7corr((length(app.Xeff7corr)+1)/2-1750 : (length(app.Xeff7corr)+1)/2+1750) - app.Y5eff7corr((length(app.Y5eff7corr)+1)/2-1750 : (length(app.Y5eff7corr)+1)/2+1750);

forwardsum1 = sum(abs(findpeaks(forward1)));
forwardsum2 = sum(abs(findpeaks(forward2)));
forwardsum3 = sum(abs(findpeaks(forward3)));
forwardsum4 = sum(abs(findpeaks(forward4)));
forwardsum5 = sum(abs(findpeaks(forward5)));
forwardsum6 = sum(abs(findpeaks(forward6)));
forwardsum7 = sum(abs(findpeaks(forward7)));
forwardsum = [forwardsum1, forwardsum2, forwardsum3, forwardsum4, forwardsum5, forwardsum6, forwardsum7];

backsum1 = sum(abs(findpeaks(back1)));
backsum2 = sum(abs(findpeaks(back2)));
backsum3 = sum(abs(findpeaks(back3)));
backsum4 = sum(abs(findpeaks(back4)));
backsum5 = sum(abs(findpeaks(back5)));
backsum6 = sum(abs(findpeaks(back6)));
backsum7 = sum(abs(findpeaks(back7)));
backsum = [backsum1, backsum2, backsum3, backsum4, backsum5, backsum6, backsum7];

leftsum1 = sum(abs(findpeaks(left1)));
leftsum2 = sum(abs(findpeaks(left2)));
leftsum3 = sum(abs(findpeaks(left3)));
leftsum4 = sum(abs(findpeaks(left4)));
leftsum5 = sum(abs(findpeaks(left5)));
leftsum6 = sum(abs(findpeaks(left6)));
leftsum7 = sum(abs(findpeaks(left7)));
leftsum = [leftsum1, leftsum2, leftsum3, leftsum4, leftsum5, leftsum6, leftsum7];

rightsum1 = sum(abs(findpeaks(right1)));
rightsum2 = sum(abs(findpeaks(right2)));
rightsum3 = sum(abs(findpeaks(right3)));
rightsum4 = sum(abs(findpeaks(right4)));
rightsum5 = sum(abs(findpeaks(right5)));
rightsum6 = sum(abs(findpeaks(right6)));
rightsum7 = sum(abs(findpeaks(right7)));
rightsum = [rightsum1, rightsum2, rightsum3, rightsum4, rightsum5, rightsum6, rightsum7];

stopsum1 = sum(abs(findpeaks(stop1)));
stopsum2 = sum(abs(findpeaks(stop2)));
stopsum3 = sum(abs(findpeaks(stop3)));
stopsum4 = sum(abs(findpeaks(stop4)));
stopsum5 = sum(abs(findpeaks(stop5)));
stopsum6 = sum(abs(findpeaks(stop6)));
stopsum7 = sum(abs(findpeaks(stop7)));
stopsum = [stopsum1, stopsum2, stopsum3, stopsum4, stopsum5, stopsum6, stopsum7];

[a1, a2, a3, a4, a5, a6, a7] = textread('parameter.txt', '%f%f%f%f%f%f%f', 1);
[b1, b2, b3, b4, b5, b6, b7] = textread('parameter.txt', '%f%f%f%f%f%f%f', 1, 'headerlines', 1);
[c1, c2, c3, c4, c5, c6, c7] = textread('parameter.txt', '%f%f%f%f%f%f%f', 1, 'headerlines', 2);
[d1, d2, d3, d4, d5, d6, d7] = textread('parameter.txt', '%f%f%f%f%f%f%f', 1, 'headerlines', 3);
[e1, e2, e3, e4, e5, e6, e7] = textread('parameter.txt', '%f%f%f%f%f%f%f', 1, 'headerlines', 4);
app.forward = a1*forwardsum1+a2*forwardsum2+a3*forwardsum3+a4*forwardsum4+a5*forwardsum5+a6*forwardsum6+a7*forwardsum7;
app.back    = b1*backsum1   +b2*backsum2   +b3*backsum3   +b4*backsum4   +b5*backsum5   +b6*backsum6   +b7*backsum7;
app.left    = c1*leftsum1   +c2*leftsum2   +c3*leftsum3   +c4*leftsum4   +c5*leftsum5   +c6*leftsum6   +c7*leftsum7;
app.right   = d1*rightsum1  +d2*rightsum2  +d3*rightsum3  +d4*rightsum4  +d5*rightsum5  +d6*rightsum6  +d7*rightsum7;
app.stop    = e1*stopsum1   +e2*stopsum2   +e3*stopsum3   +e4*stopsum4   +e5*stopsum5   +e6*stopsum6   +e7*stopsum7;
app.a = [a1, a2, a3, a4, a5, a6, a7];
app.b = [b1, b2, b3, b4, b5, b6, b7];
app.c = [c1, c2, c3, c4, c5, c6, c7];
app.d = [d1, d2, d3, d4, d5, d6, d7];
app.e = [e1, e2, e3, e4, e5, e6, e7];
        if app.ForwardButton.Value == true
            app.TrainingEditField.Value = ('Forward');
            allow = 0;
        while (app.forward>app.back || app.forward>app.right || app.forward>app.left || app.forward>app.stop)
        for i = 1:7
            if forwardsum(i)>= backsum(i)
               app.a(i) = app.a(i) - 0.01;
               app.b(i) = app.b(i) + 0.01;
               allow = allow|0;
            else
               app.a(i) = app.a(i) + 0.01;
               app.b(i) = app.b(i) - 0.01;
               allow = allow|1;
            end
            if forwardsum(i)>= leftsum(i)
               app.a(i) = app.a(i) - 0.01;
               app.c(i) = app.c(i) + 0.01;
               allow = allow|0;
            else
               app.a(i) = app.a(i) + 0.01;
               app.c(i) = app.c(i) - 0.01; 
               allow = allow|1;
            end
            if forwardsum(i)>= rightsum(i)
               app.a(i) = app.a(i) - 0.01;
               app.d(i) = app.d(i) + 0.01;
               allow = allow|0;
            else
               app.a(i) = app.a(i) + 0.01;
               app.d(i) = app.d(i) - 0.01;
               allow = allow|1;
            end
            if forwardsum(i)>= stopsum(i)
               app.a(i) = app.a(i) - 0.01;
               app.e(i) = app.e(i) + 0.01;
               allow = allow|0;
            else
               app.a(i) = app.a(i) + 0.01;
               app.e(i) = app.e(i) - 0.01;
               allow = allow|1;
            end
        end
        app.forward = app.a(1)*forwardsum1+app.a(2)*forwardsum2+app.a(3)*forwardsum3+app.a(4)*forwardsum4+app.a(5)*forwardsum5+app.a(6)*forwardsum6+app.a(7)*forwardsum7;
        app.back    = app.b(1)*backsum1   +app.b(2)*backsum2   +app.b(3)*backsum3   +app.b(4)*backsum4   +app.b(5)*backsum5   +app.b(6)*backsum6   +app.b(7)*backsum7;
        app.left    = app.c(1)*leftsum1   +app.c(2)*leftsum2   +app.c(3)*leftsum3   +app.c(4)*leftsum4   +app.c(5)*leftsum5   +app.c(6)*leftsum6   +app.c(7)*leftsum7;
        app.right   = app.d(1)*rightsum1  +app.d(2)*rightsum2  +app.d(3)*rightsum3  +app.d(4)*rightsum4  +app.d(5)*rightsum5  +app.d(6)*rightsum6  +app.d(7)*rightsum7;
        app.stop    = app.e(1)*stopsum1   +app.e(2)*stopsum2   +app.e(3)*stopsum3   +app.e(4)*stopsum4   +app.e(5)*stopsum5   +app.e(6)*stopsum6   +app.e(7)*stopsum7;
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        if allow ==0
            app.TrainingEditField.Value = ('Invalid Training Model');
            break;
        end
        end
        
        elseif app.BackButton.Value == true
            app.TrainingEditField.Value = ('Back');
            allow = 0;
        while (app.back>app.forward || app.back>app.right || app.back>app.left || app.back>app.stop)
        for i = 1:7
            if backsum(i)>= forwardsum(i)
               app.b(i) = app.b(i) - 0.01;
               app.a(i) = app.a(i) + 0.01;
               allow = allow|0;
            else
               app.b(i) = app.b(i) + 0.01;
               app.a(i) = app.a(i) - 0.01; 
               allow = allow|1;
            end
            if backsum(i)>= leftsum(i)
               app.b(i) = app.b(i) - 0.01;
               app.c(i) = app.c(i) + 0.01;
               allow = allow|0;
            else
               app.b(i) = app.b(i) + 0.01;
               app.c(i) = app.c(i) - 0.01; 
               allow = allow|1;
            end
            if backsum(i)>= rightsum(i)
               app.b(i) = app.b(i) - 0.01;
               app.d(i) = app.d(i) + 0.01;
               allow = allow|0;
            else
               app.b(i) = app.b(i) + 0.01;
               app.d(i) = app.d(i) - 0.01;
               allow = allow|1;
            end
            if backsum(i)>= stopsum(i)
               app.b(i) = app.b(i) - 0.01;
               app.e(i) = app.e(i) + 0.01;
               allow = allow|0;
            else
               app.b(i) = app.b(i) + 0.01;
               app.e(i) = app.e(i) - 0.01; 
               allow = allow|1;
            end
        end
        app.forward = app.a(1)*forwardsum1+app.a(2)*forwardsum2+app.a(3)*forwardsum3+app.a(4)*forwardsum4+app.a(5)*forwardsum5+app.a(6)*forwardsum6+app.a(7)*forwardsum7;
        app.back    = app.b(1)*backsum1   +app.b(2)*backsum2   +app.b(3)*backsum3   +app.b(4)*backsum4   +app.b(5)*backsum5   +app.b(6)*backsum6   +app.b(7)*backsum7;
        app.left    = app.c(1)*leftsum1   +app.c(2)*leftsum2   +app.c(3)*leftsum3   +app.c(4)*leftsum4   +app.c(5)*leftsum5   +app.c(6)*leftsum6   +app.c(7)*leftsum7;
        app.right   = app.d(1)*rightsum1  +app.d(2)*rightsum2  +app.d(3)*rightsum3  +app.d(4)*rightsum4  +app.d(5)*rightsum5  +app.d(6)*rightsum6  +app.d(7)*rightsum7;
        app.stop    = app.e(1)*stopsum1   +app.e(2)*stopsum2   +app.e(3)*stopsum3   +app.e(4)*stopsum4   +app.e(5)*stopsum5   +app.e(6)*stopsum6   +app.e(7)*stopsum7;
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        if allow ==0
            app.TrainingEditField.Value = ('Invalid Training Model');
            break;
        end
        end
        elseif app.LeftButton.Value == true
            app.TrainingEditField.Value = ('Left');
            allow = 0;
        while (app.left>app.back || app.left>app.forward || ap.p.left>app.right || app.left>app.stop)    
        for i = 1:7
            if leftsum(i)> backsum(i)
               app.c(i) = app.c(i) - 0.01;
               app.b(i) = app.b(i) + 0.01;
               allow = allow|0;
            else
               app.c(i) = app.c(i) + 0.01;
               app.b(i) = app.b(i) - 0.01; 
               allow = allow|1;
            end
            if leftsum(i)> forwardsum(i)
               app.c(i) = app.c(i) - 0.01;
               app.a(i) = app.a(i) + 0.01;
               allow = allow|0;
            else
               app.c(i) = app.c(i) + 0.01;
               app.a(i) = app.a(i) - 0.01; 
               allow = allow|1;
            end
            if leftsum(i)> rightsum(i)
               app.c(i) = app.c(i) - 0.01;
               app.d(i) = app.d(i) + 0.01;
               allow = allow|0;
            else
               app.c(i) = app.c(i) + 0.01;
               app.d(i) = app.d(i) - 0.01;
               allow = allow|1;
            end
            if leftsum(i)> stopsum(i)
               app.c(i) = app.c(i) - 0.01;
               app.e(i) = app.e(i) + 0.01;
               allow = allow|0;
            else
               app.c(i) = app.c(i) + 0.01;
               app.e(i) = app.e(i) - 0.01;   
               allow = allow|1;
            end
        end
        app.forward = app.a(1)*forwardsum1+app.a(2)*forwardsum2+app.a(3)*forwardsum3+app.a(4)*forwardsum4+app.a(5)*forwardsum5+app.a(6)*forwardsum6+app.a(7)*forwardsum7;
        app.back    = app.b(1)*backsum1   +app.b(2)*backsum2   +app.b(3)*backsum3   +app.b(4)*backsum4   +app.b(5)*backsum5   +app.b(6)*backsum6   +app.b(7)*backsum7;
        app.left    = app.c(1)*leftsum1   +app.c(2)*leftsum2   +app.c(3)*leftsum3   +app.c(4)*leftsum4   +app.c(5)*leftsum5   +app.c(6)*leftsum6   +app.c(7)*leftsum7;
        app.right   = app.d(1)*rightsum1  +app.d(2)*rightsum2  +app.d(3)*rightsum3  +app.d(4)*rightsum4  +app.d(5)*rightsum5  +app.d(6)*rightsum6  +app.d(7)*rightsum7;
        app.stop    = app.e(1)*stopsum1   +app.e(2)*stopsum2   +app.e(3)*stopsum3   +app.e(4)*stopsum4   +app.e(5)*stopsum5   +app.e(6)*stopsum6   +app.e(7)*stopsum7;
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        if allow ==0
            app.TrainingEditField.Value = ('Invalid Training Model');
            break;
        end
        end 
            
        elseif app.RightButton.Value == true
            app.TrainingEditField.Value = ('Right');
            allow = 0;
        while (app.right>app.back || app.right>app.forward || app.right>app.left || app.right>app.stop)
        for i = 1:7
            if rightsum(i)>= backsum(i)
               app.d(i) = app.d(i) - 0.01;
               app.b(i) = app.b(i) + 0.01;
               allow = allow|0;
            else
               app.d(i) = app.d(i) + 0.01;
               app.b(i) = app.b(i) - 0.01; 
               allow = allow|1;
            end
            if rightsum(i)>= leftsum(i)
               app.d(i) = app.d(i) - 0.01;
               app.c(i) = app.c(i) + 0.01;
               allow = allow|0;
            else
               app.d(i) = app.d(i) + 0.01;
               app.c(i) = app.c(i) - 0.01; 
               allow = allow|1;
            end
            if rightsum(i)>= forwardsum(i)
               app.d(i) = app.d(i) - 0.01;
               app.a(i) = app.a(i) + 0.01;
               allow = allow|0;
            else
               app.d(i) = app.d(i) + 0.01;
               app.a(i) = app.a(i) - 0.01;
               allow = allow|1;
            end
            if rightsum(i)>= stopsum(i)
               app.d(i) = app.d(i) - 0.01;
               app.e(i) = app.e(i) + 0.01;
               allow = allow|0;
            else
               app.d(i) = app.d(i) + 0.01;
               app.e(i) = app.e(i) - 0.01;   
               allow = allow|1;
            end
        end
        app.forward = app.a(1)*forwardsum1+app.a(2)*forwardsum2+app.a(3)*forwardsum3+app.a(4)*forwardsum4+app.a(5)*forwardsum5+app.a(6)*forwardsum6+app.a(7)*forwardsum7;
        app.back    = app.b(1)*backsum1   +app.b(2)*backsum2   +app.b(3)*backsum3   +app.b(4)*backsum4   +app.b(5)*backsum5   +app.b(6)*backsum6   +app.b(7)*backsum7;
        app.left    = app.c(1)*leftsum1   +app.c(2)*leftsum2   +app.c(3)*leftsum3   +app.c(4)*leftsum4   +app.c(5)*leftsum5   +app.c(6)*leftsum6   +app.c(7)*leftsum7;
        app.right   = app.d(1)*rightsum1  +app.d(2)*rightsum2  +app.d(3)*rightsum3  +app.d(4)*rightsum4  +app.d(5)*rightsum5  +app.d(6)*rightsum6  +app.d(7)*rightsum7;
        app.stop    = app.e(1)*stopsum1   +app.e(2)*stopsum2   +app.e(3)*stopsum3   +app.e(4)*stopsum4   +app.e(5)*stopsum5   +app.e(6)*stopsum6   +app.e(7)*stopsum7;
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        if allow ==0
            app.TrainingEditField.Value = ('Invalid Training Model');
            break;
        end
        end
        elseif app.StopButton.Value ==true
            app.TrainingEditField.Value = ('Stop');
            allow = 0;
        while (app.stop>app.back || app.stop>app.right || app.stop>app.left || app.stop>app.forward)
        for i = 1:7
            if stopsum(i)> backsum(i)
               app.e(i) = app.e(i) - 0.01;
               app.b(i) = app.b(i) + 0.01;
               allow = allow|0;
            else
               app.e(i) = app.e(i) + 0.01;
               app.b(i) = app.b(i) - 0.01;
               allow = allow|1;
            end
            if stopsum(i)> leftsum(i)
               app.e(i) = app.e(i) - 0.01;
               app.c(i) = app.c(i) + 0.01;
               allow = allow|0;
            else
               app.e(i) = app.e(i) + 0.01;
               app.c(i) = app.c(i) - 0.01; 
               allow = allow|1;
            end
            if stopsum(i)> rightsum(i)
               app.e(i) = app.e(i) - 0.01;
               app.d(i) = app.d(i) + 0.01;
               allow = allow|0;
            else
               app.e(i) = app.e(i) + 0.01;
               app.d(i) = app.d(i) - 0.01;
               allow = allow|1;
            end
            if stopsum(i)> forwardsum(i)
               app.e(i) = app.e(i) - 0.01;
               app.a(i) = app.a(i) + 0.01;
               allow = allow|0;
            else
               app.e(i) = app.e(i) + 0.01;
               app.a(i) = app.a(i) - 0.01; 
               allow = allow|1;
            end
        end
        app.forward = app.a(1)*forwardsum1+app.a(2)*forwardsum2+app.a(3)*forwardsum3+app.a(4)*forwardsum4+app.a(5)*forwardsum5+app.a(6)*forwardsum6+app.a(7)*forwardsum7;
        app.back    = app.b(1)*backsum1   +app.b(2)*backsum2   +app.b(3)*backsum3   +app.b(4)*backsum4   +app.b(5)*backsum5   +app.b(6)*backsum6   +app.b(7)*backsum7;
        app.left    = app.c(1)*leftsum1   +app.c(2)*leftsum2   +app.c(3)*leftsum3   +app.c(4)*leftsum4   +app.c(5)*leftsum5   +app.c(6)*leftsum6   +app.c(7)*leftsum7;
        app.right   = app.d(1)*rightsum1  +app.d(2)*rightsum2  +app.d(3)*rightsum3  +app.d(4)*rightsum4  +app.d(5)*rightsum5  +app.d(6)*rightsum6  +app.d(7)*rightsum7;
        app.stop    = app.e(1)*stopsum1   +app.e(2)*stopsum2   +app.e(3)*stopsum3   +app.e(4)*stopsum4   +app.e(5)*stopsum5   +app.e(6)*stopsum6   +app.e(7)*stopsum7;
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        if allow ==0
            app.TrainingEditField.Value = ('Invalid Training Model');
            break;
        end
        end
        end
fid = fopen('parameter.txt', 'w+');
fprintf(fid, '%6.3f\t', app.a);
fprintf(fid, '\n');
fprintf(fid, '%6.3f\t', app.b);
fprintf(fid, '\n');
fprintf(fid, '%6.3f\t', app.c);
fprintf(fid, '\n');
fprintf(fid, '%6.3f\t', app.d);
fprintf(fid, '\n');
fprintf(fid, '%6.3f\t', app.e);
fclose(fid);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Selection changed function: TrainingFunctionButtonGroup
        function TrainingFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrainingFunctionButtonGroup.SelectedObject;
            
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
        app.aEditField.Value = num2str(app.a);
        app.bEditField.Value = num2str(app.b);
        app.cEditField.Value = num2str(app.c);
        app.dEditField.Value = num2str(app.d);
        app.eEditField.Value = num2str(app.e);
        app.forwardEditField.Value = num2str(app.forward);
        app.backEditField.Value = num2str(app.back);
        app.leftEditField.Value = num2str(app.left);
        app.rightEditField.Value = num2str(app.right);
        app.stopEditField.Value = num2str(app.stop);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.RecordButton = uibutton(app.GridLayout, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.BrowseButton = uibutton(app.GridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TrainingFunctionButtonGroup = uibuttongroup(app.GridLayout);
            app.TrainingFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrainingFunctionButtonGroupSelectionChanged, true);
            app.TrainingFunctionButtonGroup.Title = 'TrainingFunction';
            app.ForwardButton = uiradiobutton(app.TrainingFunctionButtonGroup);
            app.ForwardButton.Text = 'Forward';
            app.ForwardButton.Value = true;
            app.BackButton = uiradiobutton(app.TrainingFunctionButtonGroup);
            app.BackButton.Text = 'Back';
            app.LeftButton = uiradiobutton(app.TrainingFunctionButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.TrainingFunctionButtonGroup);
            app.RightButton.Text = 'Right';
            app.StopButton = uiradiobutton(app.TrainingFunctionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StartTrainButton = uibutton(app.GridLayout, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Text = 'StartTrain';
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.aLabel = uilabel(app.GridLayout);
            app.aLabel.Text = 'a=';
            app.aEditField = uieditfield(app.GridLayout, 'text');
            app.bLabel = uilabel(app.GridLayout);
            app.bLabel.Text = 'b=';
            app.bEditField = uieditfield(app.GridLayout, 'text');
            app.cLabel = uilabel(app.GridLayout);
            app.cLabel.Text = 'c=';
            app.cEditField = uieditfield(app.GridLayout, 'text');
            app.dLabel = uilabel(app.GridLayout);
            app.dLabel.Text = 'd=';
            app.dEditField = uieditfield(app.GridLayout, 'text');
            app.eLabel = uilabel(app.GridLayout);
            app.eLabel.Text = 'e=';
            app.eEditField = uieditfield(app.GridLayout, 'text');
            app.forwardLabel = uilabel(app.GridLayout);
            app.forwardLabel.Text = 'forward';
            app.forwardEditField = uieditfield(app.GridLayout, 'text');
            app.backEditFieldLabel = uilabel(app.GridLayout);
            app.backEditFieldLabel.Text = 'back';
            app.backEditField = uieditfield(app.GridLayout, 'text');
            app.leftEditFieldLabel = uilabel(app.GridLayout);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.GridLayout, 'text');
            app.rightEditFieldLabel = uilabel(app.GridLayout);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.GridLayout, 'text');
            app.stopEditFieldLabel = uilabel(app.GridLayout);
            app.stopEditFieldLabel.Text = 'stop';
            app.stopEditField = uieditfield(app.GridLayout, 'text');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StopButton_2 = uibutton(app.GridLayout, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.TrainingEditFieldLabel = uilabel(app.GridLayout);
            app.TrainingEditFieldLabel.Text = 'Training';
            app.TrainingEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speechRecognitionMARKVIII

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end